classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataProcessorUIFigure  matlab.ui.Figure
        PlotButton             matlab.ui.control.Button
        FolderIcon             matlab.ui.control.Image
        FileDirectory          matlab.ui.control.EditField
        FileFolderLabel        matlab.ui.control.Label
        UILabel                matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        TaskDropDown           matlab.ui.control.DropDown
        TaskLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        filepath = '';  % the file that is being processed, saved or loaded
        datastate = "none"; % tracks the state of the data moving in or out
        task = '';  % the task we are choosing to run
    end
    
    properties (Access = public)
        storedata % object of StoreData class used to wrap the data
    end

    methods (Access = private)

        function processData(app)
            app.storedata = StoreData;  % assign file data to data wrapper object
            popup = uiprogressdlg(app.DataProcessorUIFigure, 'Title',"Processing"); % loading bar
            popup.Value = 0.1;
            app.storedata.line1 = readtable(app.filepath);  % read raw data from file
            app.storedata.file = app.filepath;
            app.datastate = "unsaved";
            popup.Value = 0.99;
            close(popup);
            disp(['Processed data from ', app.filepath]);
        end
        
        function saveData(app)
            writtendata = app.storedata; % reassign a local variable name to the data object per rules for save() function
            popup = uiprogressdlg(app.DataProcessorUIFigure, 'Title',"Saving"); % loading bar
            popup.Value = 0.1;
            save(app.filepath,'writtendata',"-v7.3","-nocompression");   % save the file
            app.datastate = "saved";
            popup.Value = 0.99;
            close(popup);
            disp(['Data saved as ', app.filepath]);
        end

        function loadData(app)
            popup = uiprogressdlg(app.DataProcessorUIFigure, 'Title',"Loading"); % loading bar
            popup.Value = 0.1;
            load(app.filepath,'writtendata');    % load the file
            app.storedata = writtendata;    % assign file data to data wrapper object
            app.datastate = "loaded";
            popup.Value = 0.99;
            close(popup);
            disp(['Loaded data from ', app.filepath]);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.DataProcessorUIFigure,'center');    % move the window to the center of the screen
        end

        % Image clicked function: FolderIcon
        function FolderIconImageClicked(app, event)
            value = app.task;
            switch value
                case "Process Data"
                    [file,path] = uigetfile('*.csv');   % select the raw data file to process
                case "Save Data"
                    [file,path] = uiputfile('*.mat');   % choose destination and save file name
                case "Load Data"
                    [file,path] = uigetfile('*.mat');   % select file to load
                otherwise
                    return
            end
            figure(app.DataProcessorUIFigure)
            if isequal(file,0)  % ignore if no selection made
                app.filepath = '';  % clear out selection variable
                app.FileDirectory.Value = ''; % clear the selection box text
            else
                app.filepath = fullfile(path,file); % store the file and directory selection
                app.FileDirectory.Value = app.filepath; % display selection in text box
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            value = app.TaskDropDown.Value;
            switch value
                case 'Process Data'
                    app.processData();
                case 'Save Data'
                    app.saveData();
                case 'Load Data'
                    app.loadData();
            end
            if app.datastate ~= "none"
                app.PlotButton.Enable = 'on';
            end
        end

        % Close request function: DataProcessorUIFigure
        function closeMenu(app, event)
            if app.datastate == "unsaved"   % if there's unsaved data loaded, prompt to save before quitting
                value = uiconfirm(app.DataProcessorUIFigure,"Quit without saving??",...
                    "Quit without saving","Options", ["Don't Quit","Quit without saving"],"Icon","warning");
                switch value
                    case "Don't Quit"
                        return
                end
            end    
            delete(app) % delete the app parent object on close
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            app.task = app.TaskDropDown.Value;
            app.filepath = '';  % clear out selection variable
            app.FileDirectory.Value = ''; % clear the selection box text
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Plotter(app.storedata);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataProcessorUIFigure = uifigure('Visible', 'off');
            app.DataProcessorUIFigure.Name = 'DataProcessor';
            app.DataProcessorUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeMenu, true);
            app.TaskLabel = uilabel(app.DataProcessorUIFigure);
            app.TaskLabel.Text = 'Task:';
            app.TaskDropDown = uidropdown(app.DataProcessorUIFigure);
            app.TaskDropDown.Items = {'Select Task', 'Process Data', 'Save Data', 'Load Data'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = 'Select Task';
            app.StartButton = uibutton(app.DataProcessorUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UILabel = uilabel(app.DataProcessorUIFigure);
            app.UILabel.Text = 'DataProcessor';
            app.FileFolderLabel = uilabel(app.DataProcessorUIFigure);
            app.FileFolderLabel.Text = 'File/Folder:';
            app.FileDirectory = uieditfield(app.DataProcessorUIFigure, 'text');
            app.FileDirectory.Editable = 'off';
            app.FolderIcon = uiimage(app.DataProcessorUIFigure);
            app.FolderIcon.ImageClickedFcn = createCallbackFcn(app, @FolderIconImageClicked, true);
            app.FolderIcon.ImageSource = fullfile(pathToMLAPP, 'folder.png');
            app.PlotButton = uibutton(app.DataProcessorUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.DataProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataProcessorUIFigure)
        end
    end
end