classdef Plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataProcessorUIFigure  matlab.ui.Figure
        TimeLabel              matlab.ui.control.Label
        GXPanel                matlab.ui.container.Panel
        FileEditField          matlab.ui.control.EditField
        FileLabel              matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        UILabel                matlab.ui.control.Label
        PlotAllButton          matlab.ui.control.Button
    end

    properties (Access = private)
        plotdata % processed data
        gx  % geoaxes
        tracktime   % time length of track
        ac_loc
        emit_loc
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.plotdata = data;
            app.FileEditField.Value = app.plotdata.file;

            app.gx = geoaxes(app.GXPanel); %,'Basemap','topographic');
            app.gx.Position = [0 0 1 1];
            %geolimits([45 62],[-149 -123])
            app.gx.LatitudeAxis.TickLabels = {};
            app.gx.LongitudeAxis.TickLabels = {};
            app.gx.LatitudeLabel.String = '';
            app.gx.LongitudeLabel.String = '';
            hold(app.gx,'on');
            
            tlen = length(app.plotdata.line1.utc);
            app.tracktime = app.plotdata.line1.utc(tlen)-app.plotdata.line1.utc(1);
            app.Slider.Limits = [0 tlen];
            app.Slider.MajorTickLabels = {};
            app.TimeLabel.Text = [num2str(app.tracktime) ' sec'];
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            delete(app.ac_loc);
            app.ac_loc = geoscatter(app.gx,app.plotdata.line1.ac_lat,app.plotdata.line1.ac_long, "filled","blue");
            app.emit_loc = geoscatter(app.gx,app.plotdata.line1.emit_true_lat,app.plotdata.line1.emit_true_long,"filled","red");
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            delete(app.ac_loc);
            set(app.Slider, 'Value', value);
            app.ac_loc = geoscatter(app.gx,app.plotdata.line1.ac_lat(value),app.plotdata.line1.ac_long(value), "filled","blue");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataProcessorUIFigure = uifigure('Visible', 'off');
            app.DataProcessorUIFigure.Name = 'DataProcessor';
            app.PlotAllButton = uibutton(app.DataProcessorUIFigure, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.UILabel = uilabel(app.DataProcessorUIFigure);
            app.UILabel.Text = 'DataPlotter';
            app.Label = uilabel(app.DataProcessorUIFigure);
            app.Label.Text = '0';
            app.Slider = uislider(app.DataProcessorUIFigure);
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FileLabel = uilabel(app.DataProcessorUIFigure);
            app.FileLabel.Text = 'File: ';
            app.FileEditField = uieditfield(app.DataProcessorUIFigure, 'text');
            app.FileEditField.Editable = 'off';
            app.FileEditField.Value = '(location of processed data file)';
            app.GXPanel = uipanel(app.DataProcessorUIFigure);
            app.TimeLabel = uilabel(app.DataProcessorUIFigure);
            app.TimeLabel.Text = '1000';
            % Show the figure after all components are created
            app.DataProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plotter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataProcessorUIFigure)
        end
    end
end